classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PhaseSliderLabel     matlab.ui.control.Label
        tWeightSlider        matlab.ui.control.Slider
        WeightSliderLabel    matlab.ui.control.Label
        mWeightSlider        matlab.ui.control.Slider
        mWeightLabel         matlab.ui.control.Label
        tWeightLabel         matlab.ui.control.Label
        ImportmatfileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Pax % Polar axes
        Data % Data
       
    end
    
    methods (Access = private)
        
        function updateplot(app)
            global gcolor; setpath;
            mWeight = app.mWeightSlider.Value;
            tWeight = app.tWeightSlider.Value;
            zWeight = getcomplex(mWeight, tWeight);
            D = app.Data;
            modes = unique(D.modes);
            app.Pax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                a = v_isakovich(D(i,:), zWeight);
                z = linspace(D.zP2P(i), a);
                polarplot(app.Pax, z, '.', ...
                    'LineWidth', 2, ...
                    'Color',  gcolor(g,:));
                app.Pax.NextPlot = 'add';
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [220 40 400 400];
            app.Data = load(fullfile('results\summary.mat'));
            app.Data = app.Data.D;
            updateplot(app);
        end

        % Value changed function: mWeightSlider, tWeightSlider
        function SliderChange(app, event)
            app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
            app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.1f');   
            if exist('app.Data', 'var')
                updateplot(app);
            end
            updateplot(app);
        end

        % Button pushed function: ImportmatfileButton
        function ImportmatfileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            if ~isequal(file,0)
                app.Data = load(fullfile(path, file));
                app.Data = app.Data.D;
                updateplot(app);
            end            
        end

        % Callback function
        function AutoScaleValueChanged(app, event)
            
        end

        % Callback function
        function MaximumVibrationValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.tWeightSlider = uislider(app.UIFigure);
            app.tWeightSlider.Limits = [0 360];
            app.tWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.WeightSliderLabel = uilabel(app.UIFigure);
            app.WeightSliderLabel.Text = 'Weight';
            app.mWeightSlider = uislider(app.UIFigure);
            app.mWeightSlider.Limits = [0 200];
            app.mWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.mWeightLabel = uilabel(app.UIFigure);
            app.mWeightLabel.Text = 'NaN';
            app.tWeightLabel = uilabel(app.UIFigure);
            app.tWeightLabel.Text = 'NaN';
            app.ImportmatfileButton = uibutton(app.UIFigure, 'push');
            app.ImportmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmatfileButtonPushed, true);
            app.ImportmatfileButton.Text = 'Import mat-file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end